classdef Peak_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        PeakAnalyzerLabel            matlab.ui.control.Label
        LeftAxisDropDownLabel        matlab.ui.control.Label
        LeftAxisDropDown             matlab.ui.control.DropDown
        RightAxisDropDownLabel       matlab.ui.control.Label
        RightAxisDropDown            matlab.ui.control.DropDown
        SeperatePlotButton           matlab.ui.control.Button
        Tree                         matlab.ui.container.Tree
        MissingValuesNode            matlab.ui.container.TreeNode
        MissingValuesCountEditFieldLabel  matlab.ui.control.Label
        MissingValuesCountEditField  matlab.ui.control.NumericEditField
        PlotSigXCoordinatesCheckBox  matlab.ui.control.CheckBox
        SetXAxisDropDownLabel        matlab.ui.control.Label
        SetXAxisDropDown             matlab.ui.control.DropDown
    end


    properties (Access = private)
        %tdms_data % Description
        parent_app % Description
    end

    methods (Access = private)
    
        function Fit_Parameter_Plot(app, ax)
            % ax: axes to plot on
            
            % Get: significant x-coordinates
            % - These are used for plotting vertical lines at interesting coordinates
            sig_x = [];
            if app.PlotSigXCoordinatesCheckBox.Value
                sig_x = app.parent_app.tdms_data(1).plot_info.sig_x_coordinates;
            end
            
            project_config = Get_Project_Struct();
            
            % Get Measurment and Unit of x-axis
            x_measurment = app.SetXAxisDropDown.Value;
            x_unit = project_config.map_measurement_unit(x_measurment);
            x_axis_lbl = [x_measurment, ' (', x_unit, ')'];
            
            % Plot: based on dropdown settings
            Analyze_Tracked_Peaks(ax, ...
                                 {app.parent_app.tdms_data.peaks_tracked},  ...
                                 [app.parent_app.tdms_data.(x_measurment)], ...
                                 x_axis_lbl,                    ...
                                 app.LeftAxisDropDown.Value,    ...
                                 app.RightAxisDropDown.Value,   ...
                                 sig_x)
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            
            % STORE: reffernce to parent application
            app.parent_app = parent_app;
            
            % SET: missing values in missing values tree
            count_missing = 0;
            for i = 1:length(app.parent_app.tdms_data)
                if isempty(app.parent_app.tdms_data(i).peaks_tracked)
                    uitreenode(app.MissingValuesNode,'Text',num2str(app.parent_app.tdms_data(i).id));
                    count_missing = count_missing + 1;
                end
            end
            expand(app.Tree,'All');
            app.MissingValuesCountEditField.Value = count_missing;
            
            % Set X-axis
            project_config = Get_Project_Struct();
            app.SetXAxisDropDown.Items = project_config.map_measurement_unit.keys;
            
            % Plot
            %PlotPeakInfoButtonPushed(app)
            app.Fit_Parameter_Plot(app.UIAxes);
        end

        % Value changed function: LeftAxisDropDown, 
        % PlotSigXCoordinatesCheckBox, RightAxisDropDown
        function PlotPeakInfoButtonPushed(app, event)
           app.Fit_Parameter_Plot(app.UIAxes);
% %             % Get: significant x-coordinates
% %             % - These are used for plotting vertical lines at interesting coordinates
% %             sig_x = [];
% %             if app.PlotSigXCoordinatesCheckBox.Value
% %                 sig_x = app.parent_app.tdms_data(1).plot_info.sig_x_coordinates;
% %             end
% %             
% %             project_config = Get_Project_Struct();
% %             
% %             % Get Measurment and Unit of x-axis
% %             x_measurment = app.SetXAxisDropDown.Value;
% %             x_unit = project_config.map_measurement_unit(x_measurment);
% %             x_axis_lbl = [x_measurment, ' (', x_unit, ')'];
% %             
% %             % Plot: based on dropdown settings
% %             Analyze_Tracked_Peaks(app.UIAxes, ...
% %                                  {app.parent_app.tdms_data.peaks_tracked},  ...
% %                                  [app.parent_app.tdms_data.(x_measurment)], ...
% %                                  x_axis_lbl,                    ...
% %                                  app.LeftAxisDropDown.Value,    ...
% %                                  app.RightAxisDropDown.Value,   ...
% %                                  sig_x)
                            
        end

        % Button pushed function: SeperatePlotButton
        function SeperatePlotButtonPushed(app, event)
          figure()
          app.Fit_Parameter_Plot(gca)
%             % Get: significant x-coordinates
%             sig_x = [];
%             if app.PlotSigXCoordinatesCheckBox.Value
%                 sig_x = app.parent_app.tdms_data(1).plot_info.sig_x_coordinates;
%             end
%             
%             % Plot: based on dropdown settings
%             figure()
%             Analyze_Tracked_Peaks(gca, ...
%                                  {app.parent_app.tdms_data.peaks_tracked}, ...
%                                  [app.parent_app.tdms_data.temperature],   ...
%                                  app.LeftAxisDropDown.Value,    ...
%                                  app.RightAxisDropDown.Value,   ...
%                                  sig_x)
                            
        end

        % Callback function
        function PlotPeakAtTempButtonPushed(app, event)
            
            % Get: the tdms struct that contains the given
            %      temperature
            given_temp = app.TempEditField.Value;
            tdms_at_temp = 0;
            for i = 1:length(app.parent_app.tdms_data)
                if app.parent_app.tdms_data(i).temperature == given_temp
                    tdms_at_temp = app.parent_app.tdms_data(i); 
                    break
                end
            end

            % Set: info to plot
            plot_info = Get_Plot_Struct();
            plot_info.raw = 1;
            plot_info.peaks_raw_set = [1,1];
            plot_info.preprocess = tdms_at_temp.plot_info.preprocess;
            
            % Plot
            figure();
            ax = gca;
            Plot_File(ax, tdms_at_temp,plot_info);
            
            % Set: Axis Info
            title(ax,['Temperature: ',num2str(given_temp)]);
            ylabel(ax,"Voltage (V)");
            xlabel(ax,"Frequency (khz)");
            
            % PLOT: Peak Being Tracked
           
            % Get: Frequency of peak corresponding to the temperature
            pt = tdms_at_temp.peaks_tracked;
            peak_freq = pt.Frequencies;

            % Plot: dashed line at the peak of interest
            y1=get(gca,'ylim');
            plot(ax,[peak_freq, peak_freq],y1,'r--','DisplayName','Peak Frequency Being Tracked');
            
            % Zoom:
            if app.ZoomFitRegionForTrackedPeakCheckBox.Value == true
                
                % min/max freq
                min_freq = min(pt.fit.frequencies);
                max_freq = max(pt.fit.frequencies);
                
                freq_bool = min_freq <= tdms_at_temp.frequency & ...
                            tdms_at_temp.frequency <= max_freq;
                signal_values = [tdms_at_temp.signal_y(freq_bool), ...
                    tdms_at_temp.signal_x(freq_bool)];
                
                ylim(gca,[min([signal_values,pt.fit.signal_x,pt.fit.signal_y]), ...
                    max([signal_values,pt.fit.signal_x,pt.fit.signal_y])]);
                xlim(gca,[min(pt.fit.frequencies), max(pt.fit.frequencies)])
            end
            legend(ax)
        end

        % Value changed function: SetXAxisDropDown
        function SetXAxisDropDownValueChanged(app, event)
            app.PlotPeakInfoButtonPushed()
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PeakAnalyzerLabel = uilabel(app.UIFigure);
            app.PeakAnalyzerLabel.Text = 'Peak Analyzer';
            app.LeftAxisDropDownLabel = uilabel(app.UIFigure);
            app.LeftAxisDropDownLabel.Text = 'Left Axis';
            app.LeftAxisDropDown = uidropdown(app.UIFigure);
            app.LeftAxisDropDown.Items = {'gamma', 'f_0', 'theta', 'A', 'frequency', 'V'};
            app.LeftAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotPeakInfoButtonPushed, true);
            app.LeftAxisDropDown.Value = 'gamma';
            app.RightAxisDropDownLabel = uilabel(app.UIFigure);
            app.RightAxisDropDownLabel.Text = 'Right Axis';
            app.RightAxisDropDown = uidropdown(app.UIFigure);
            app.RightAxisDropDown.Items = {'None', 'gamma', 'f_0', 'theta', 'A', 'V'};
            app.RightAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotPeakInfoButtonPushed, true);
            app.RightAxisDropDown.Value = 'None';
            app.SeperatePlotButton = uibutton(app.UIFigure, 'push');
            app.SeperatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SeperatePlotButtonPushed, true);
            app.SeperatePlotButton.Text = 'Seperate Plot';
            app.Tree = uitree(app.UIFigure);
            app.MissingValuesNode = uitreenode(app.Tree);
            app.MissingValuesNode.Text = 'Missing Values';
            app.MissingValuesCountEditFieldLabel = uilabel(app.UIFigure);
            app.MissingValuesCountEditFieldLabel.Text = 'Missing Values Count';
            app.MissingValuesCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.MissingValuesCountEditField.Editable = 'off';
            app.PlotSigXCoordinatesCheckBox = uicheckbox(app.UIFigure);
            app.PlotSigXCoordinatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotPeakInfoButtonPushed, true);
            app.PlotSigXCoordinatesCheckBox.Text = 'Plot Sig-X-Coordinates';
            app.SetXAxisDropDownLabel = uilabel(app.UIFigure);
            app.SetXAxisDropDownLabel.Text = 'Set X-Axis';
            app.SetXAxisDropDown = uidropdown(app.UIFigure);
            app.SetXAxisDropDown.Items = {};
            app.SetXAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @SetXAxisDropDownValueChanged, true);
            app.SetXAxisDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Peak_Analyzer(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end