classdef rpr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RPRFileGeneratorLabel          matlab.ui.control.Label
        TextDescription                matlab.ui.control.TextArea
        OpenRPRDescriptionButton       matlab.ui.control.Button
        GenerateFilesButton            matlab.ui.control.Button
        FieldsListBoxLabel             matlab.ui.control.Label
        FieldsListBox                  matlab.ui.control.ListBox
        FieldDescriptionTextAreaLabel  matlab.ui.control.Label
        FieldDescriptionTextArea       matlab.ui.control.TextArea
        FieldValueEditFieldLabel       matlab.ui.control.Label
        FieldValueEditField            matlab.ui.control.EditField
        NextButton                     matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        UsingDefaultValueLampLabel     matlab.ui.control.Label
        UsingDefaultValueLamp          matlab.ui.control.Lamp
        FrequenciesTextAreaLabel       matlab.ui.control.Label
        FrequenciesTextArea            matlab.ui.control.TextArea
        FrequenciesDescriptionscrolltoseeExTextAreaLabel  matlab.ui.control.Label
        FrequenciesDescriptionscrolltoseeExTextArea  matlab.ui.control.TextArea
        RPRrectangularparallelepipedresonatorLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        current_field_info % Description
        default_field_info % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % --- SET FIELD INFO ---

            % 1.) number of moduli
            field_info(1).default = '2';
            field_info(1).desc    = ['Input: integer (9 max)' newline '* Ex: ' field_info(1).default newline 'number of moduli'];
            
            % set the rest of field values (Temporarily)
            for i=2:10
                field_info(i) = field_info(1);
            end
            
            % 2.) number of dimensions
            field_info(2).default = '0';
            field_info(2).desc    = ['Input: integer (0 or 3)' newline '* Ex: ' field_info(2).default newline 'Number of dimension to be fit.' newline ' * Only the RPR code fits dimensions.' newline ' * So it should be set to 0 for the cylinder and sphere input files.'];
            
            % 3.) order of the basis set
            field_info(3).default = '0';
            field_info(3).desc    = ['Input: integer (16 max)' newline '* Ex: ' field_info(3).default];
            
            % 4.) Dimensions
            field_info(10).default = '0.474481 0.377003 0.484186';
            field_info(10).desc    = ['Input: 3 floatig point numbers.' newline 'Ex: ' field_info(10).default];
            
            % set values to defaults
            [field_info.value] = deal(field_info.default);
            
            % associate field info with labels.
            app.FieldsListBox.ItemsData = field_info;
            
            app.default_field_info = field_info;
            app.current_field_info = field_info;
            
            example = ['0.089937 0. 1.00' newline ...
                        '0.122485 0. 0.00' newline  ...
                        '0.123037 0. 1.00' newline  ...
                        '0.133300 0. 1.00' newline  ...
                        '0.136562 0. 1.00' newline  ...
                        '0.140502 0. 1.00' newline  ...
                        '0.144324 0. 1.00' newline  ...
                        '0. 0. 0.        ' newline  ...
                        '0.150841 0. 1.00' newline  ...
                        '0.150964 0. 1.00' newline  ...
                        '0.162553 0. 1.00' newline  ...
                        '0.163018 0. 1.00' newline  ...
                        '0.164211 0. 1.00' newline  ...
                        '0.171321 0. 1.00' newline  ...
                        '0.190858 0. 1.00' newline  ...
                        '0.191871 0. 1.00' newline  ...
                        '0.194339 0. 1.00' newline  ...
                        '0.197951 0. 1.00' newline  ...
                        '0.198913 0. 1.00' newline  ...
                        '0.200511 0. 1.00' newline  ...
                        '0.203233 0. 1.00' newline  ...
                        '0.207026 0. 1.00' newline  ...
                        '0.207831 0. 1.00' newline  ...
                        '0.209198 0. 1.00' newline  ...
                        '0.209741 0. 1.00' newline];


        end

        % Value changed function: FieldsListBox
        function FieldsListBoxValueChanged(app, event)
            value = app.FieldsListBox.Value;
            app.FieldDescriptionTextArea.Value = value.desc;
            app.FieldValueEditField.Value = value.default;
        end

        % Value changed function: FieldValueEditField
        function FieldValueEditFieldValueChanged(app, event)
            field_value = app.FieldValueEditField.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RPRFileGeneratorLabel = uilabel(app.UIFigure);
            app.RPRFileGeneratorLabel.Text = 'RPR File Generator';
            app.TextDescription = uitextarea(app.UIFigure);
            app.TextDescription.Editable = 'off';
            app.TextDescription.Value = {'This App is used to:'; '    * generate files used for rpr.exe'; '    * run the files generated using rpr.exe'; ''; 'rpr. exe is used to:'; '* Determine the elastic moduli from the resonances of a '; '  rectangular parallelepiped resonator, cylinder, or sphere.'};
            app.OpenRPRDescriptionButton = uibutton(app.UIFigure, 'push');
            app.OpenRPRDescriptionButton.Text = 'Open RPR Description';
            app.GenerateFilesButton = uibutton(app.UIFigure, 'push');
            app.GenerateFilesButton.Text = 'Generate Files';
            app.FieldsListBoxLabel = uilabel(app.UIFigure);
            app.FieldsListBoxLabel.Text = 'Fields';
            app.FieldsListBox = uilistbox(app.UIFigure);
            app.FieldsListBox.Items = {'Number of Moduli (9 max)', 'Number of dimension to be fit (0 or 3)', 'Order of the basis set (16 max)', 'Indicate a fit (any integer 0-600)', 'mass (in grams)', 'convergence parameter (any positive real number)', 'output to screen (0 or 1)', 'moduli free to float (9 0''s or 1s)', 'guesses for c11 and c44 (2 floating point #''s)', 'Dimensions'};
            app.FieldsListBox.ValueChangedFcn = createCallbackFcn(app, @FieldsListBoxValueChanged, true);
            app.FieldsListBox.Value = 'Number of Moduli (9 max)';
            app.FieldDescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.FieldDescriptionTextAreaLabel.Text = 'Field Description';
            app.FieldDescriptionTextArea = uitextarea(app.UIFigure);
            app.FieldDescriptionTextArea.Editable = 'off';
            app.FieldValueEditFieldLabel = uilabel(app.UIFigure);
            app.FieldValueEditFieldLabel.Text = 'Field Value';
            app.FieldValueEditField = uieditfield(app.UIFigure, 'text');
            app.FieldValueEditField.ValueChangedFcn = createCallbackFcn(app, @FieldValueEditFieldValueChanged, true);
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.Text = 'Previous';
            app.UsingDefaultValueLampLabel = uilabel(app.UIFigure);
            app.UsingDefaultValueLampLabel.Text = 'Using Default Value';
            app.UsingDefaultValueLamp = uilamp(app.UIFigure);
            app.UsingDefaultValueLamp.Enable = 'off';
            app.FrequenciesTextAreaLabel = uilabel(app.UIFigure);
            app.FrequenciesTextAreaLabel.Text = 'Frequencies';
            app.FrequenciesTextArea = uitextarea(app.UIFigure);
            app.FrequenciesDescriptionscrolltoseeExTextAreaLabel = uilabel(app.UIFigure);
            app.FrequenciesDescriptionscrolltoseeExTextAreaLabel.Text = 'Frequencies Description (scroll to see Ex.)';
            app.FrequenciesDescriptionscrolltoseeExTextArea = uitextarea(app.UIFigure);
            app.FrequenciesDescriptionscrolltoseeExTextArea.Editable = 'off';
            app.FrequenciesDescriptionscrolltoseeExTextArea.Value = {'* The remainder are frequencies in MHz–400 frequencies is the maximum.'; ''; '* 0.is not used but must be a real number.'; ''; '* 1.00 indicates a weight of 1.00 for this mode–any real positive number is ok.'; ''; '* 0. 0. 0. Indicates a mode that is missing but beleived to be present.'; ''; '* Example Input:'; ''; '0.089937 0. 1.00'; '0.122485 0. 0.00'; '0.123037 0. 1.00'; '0.133300 0. 1.00 0.136562 0. 1.00 0.140502 0. 1.00 0.144324 0. 1.00'; '0. 0. 0.'; '0.150841 0. 1.00 0.150964 0. 1.00'; '0.162553 0. 1.00 0.163018 0. 1.00 0.164211 0. 1.00 0.171321 0. 1.00 0.190858 0. 1.00 0.191871 0. 1.00 0.194339 0. 1.00 0.197951 0. 1.00 0.198913 0. 1.00 0.200511 0. 1.00 0.203233 0. 1.00 0.207026 0. 1.00 0.207831 0. 1.00 0.209198 0. 1.00'; '0.209741 0. 1.00'};
            app.RPRrectangularparallelepipedresonatorLabel = uilabel(app.UIFigure);
            app.RPRrectangularparallelepipedresonatorLabel.Text = 'RPR: rectangular parallelepiped resonator';
        end
    end

    methods (Access = public)

        % Construct app
        function app = rpr

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end